classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_2               matlab.ui.control.Label
        StartButtonS          matlab.ui.control.StateButton
        Label                 matlab.ui.control.Label
        VeryloudLamp          matlab.ui.control.Lamp
        VeryloudLampLabel     matlab.ui.control.Label
        NoiseLevelGauge       matlab.ui.control.LinearGauge
        NoiseLevelGaugeLabel  matlab.ui.control.Label
        STATUSLabel           matlab.ui.control.Label
        NormalLamp            matlab.ui.control.Lamp
        NormalLampLabel       matlab.ui.control.Label
        loudLamp              matlab.ui.control.Lamp
        loudLampLabel         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj % Arduino
        Count =0;
        dbData = [];
    end
    
    methods (Access = private)
        
        function readData(app, src, ~)
            data = readline(src);
            app.dbData(end+1) = str2double(data);
            app.Count = app.Count + 1;
            disp(app.dbData);
            dbAllData = app.dbData;
            CC = app.Count;
            dbData50 = dbAllData(end-50:end);
            if app.StartButtonS.Value == true
                axis(app.UIAxes,[0, 50, 0, 250]);
                dbValue = app.dbData(end);
                app.NoiseLevelGauge.Value = dbValue;
                app.NormalLamp.Color = [0.90, 0.90, 0.90];
                app.loudLamp.Color = [0.90, 0.90, 0.90];
                app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
                if dbValue < 80
                    app.NormalLamp.Color = 'green';
                elseif dbValue >= 80 && dbValue < 120
                    app.loudLamp.Color = 'yellow';
                elseif dbValue >= 120
                    app.VeryloudLamp.Color = 'red';
                end
                app.Label.Text = string(dbValue) + " " + "dB";
                lendata = numel(dbAllData)
                if CC < 50
                    plot(app.UIAxes, dbAllData);
                    app.Label_2.Text = "AVG" + " " + string(mean(dbAllData(end-lendata+1:end))) + " " + "dB";
                else
                    plot(app.UIAxes, dbAllData(end-50:end));
                    app.Label_2.Text = "AVG" + " " + string(mean(dbData50)) + " " + "dB";
                end
            else
                cla(app.UIAxes)
                app.NoiseLevelGauge.Value = 0;
                app.NormalLamp.Color = [0.90, 0.90, 0.90];
                app.loudLamp.Color = [0.90, 0.90, 0.90];
                app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
                app.Label.Text = "0" + " " + "dB";
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoObj = serialport("/dev/cu.usbserial-0001", 9600);
            configureTerminator(app.arduinoObj, "CR/LF");
            flush(app.arduinoObj);
            configureCallback(app.arduinoObj,"terminator",@(src, event) readData(app, src, event));
        end

        % Callback function
        function StopButtonPushed(app, event)
            cla(app.UIAxes)
            app.NoiseLevelGauge.Value = 0;
            app.NormalLamp.Color = [0.90, 0.90, 0.90];
            app.loudLamp.Color = [0.90, 0.90, 0.90];
            app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
            app.Label.Text = "0" + " " + "dB";
        end

        % Value changed function: StartButtonS
        function StartButtonSValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ADC Value ( dB )')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 25 50 75 100 125 150 175 200 225 250];
            app.UIAxes.YTickLabel = {'0'; '25'; '50'; '75'; '100'; '125'; '150'; '175'; '200'; '225'; '250'};
            colormap(app.UIAxes, 'copper')
            app.loudLampLabel = uilabel(app.UIFigure);
            app.loudLampLabel.Text = 'loud';
            app.loudLamp = uilamp(app.UIFigure);
            app.NormalLampLabel = uilabel(app.UIFigure);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.UIFigure);
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS';
            app.NoiseLevelGaugeLabel = uilabel(app.UIFigure);
            app.NoiseLevelGaugeLabel.Text = 'Noise Level';
            app.NoiseLevelGauge = uigauge(app.UIFigure, 'linear');
            app.NoiseLevelGauge.Limits = [0 250];
            app.VeryloudLampLabel = uilabel(app.UIFigure);
            app.VeryloudLampLabel.Text = 'Very loud';
            app.VeryloudLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StartButtonS = uibutton(app.UIFigure, 'state');
            app.StartButtonS.ValueChangedFcn = createCallbackFcn(app, @StartButtonSValueChanged, true);
            app.StartButtonS.Text = 'Start / Stop';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end